classdef appDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        AfilewiththatnamealreadyexistshowdoyouwanttoproceedPanel  matlab.ui.container.Panel
        createnewversionoftherecordingButton  matlab.ui.control.Button
        changefilenameoutputfolderButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        createNewVersionDialogBox
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, appRecording)
            app.CallingApp = appRecording;

        end

        % Button pushed function: createnewversionoftherecordingButton
        function createnewversionoftherecordingButtonPushed(app, event)
            app.createNewVersionDialogBox = 1;
            selectBehaviourAfterDialogBox(app.CallingApp,app.createNewVersionDialogBox);
            delete(app)
        end

        % Button pushed function: changefilenameoutputfolderButton
        function changefilenameoutputfolderButtonPushed(app, event)
            app.createNewVersionDialogBox = 0;
            selectBehaviourAfterDialogBox(app.CallingApp,app.createNewVersionDialogBox);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AfilewiththatnamealreadyexistshowdoyouwanttoproceedPanel = uipanel(app.UIFigure);
            app.AfilewiththatnamealreadyexistshowdoyouwanttoproceedPanel.Title = 'A file with the same name already exists in the folder, how do you want to proceed?';
            app.changefilenameoutputfolderButton = uibutton(app.AfilewiththatnamealreadyexistshowdoyouwanttoproceedPanel, 'push');
            app.changefilenameoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @changefilenameoutputfolderButtonPushed, true);
            app.changefilenameoutputfolderButton.Text = 'change file name/ output folder';
            app.createnewversionoftherecordingButton = uibutton(app.AfilewiththatnamealreadyexistshowdoyouwanttoproceedPanel, 'push');
            app.createnewversionoftherecordingButton.ButtonPushedFcn = createCallbackFcn(app, @createnewversionoftherecordingButtonPushed, true);
            app.createnewversionoftherecordingButton.Text = 'create new version of the recording';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end