classdef appRecording < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        SessionEditField               matlab.ui.control.EditField
        SessionEditFieldLabel          matlab.ui.control.Label
        MouseIDEditField               matlab.ui.control.EditField
        MouseIDEditFieldLabel          matlab.ui.control.Label
        DurationofRecordingminEditField  matlab.ui.control.NumericEditField
        DurationofRecordingminEditFieldLabel  matlab.ui.control.Label
        SelectSerialPortDropDown       matlab.ui.control.DropDown
        SelectSerialPortDropDownLabel  matlab.ui.control.Label
        SelectOutputFolderButton       matlab.ui.control.Button
        Timer                          matlab.ui.control.NumericEditField
        Lamp                           matlab.ui.control.Lamp
        SelectOutputFolderEditField    matlab.ui.control.EditField
        StartRecordingButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        DialogApp % Description
        outputName
        outputFolder
    end
  
    methods (Access = public)
        
        function initializeReadSerialPort(app,...
            mouseId,...
            session,...
            durationRecording,...
            serialPort)

            s=serialport(serialPort,9600);
            
            timeRecording = durationRecording * 60;
            countData = 1;
            data = cell(1,50*timeRecording);
            timeStamps = nan(1,50*timeRecording);
            tic;
            timeElapsed = toc;
            
            disp('Start of Recording')

            app.StartRecordingButton.Text = 'Recording...';
            app.Lamp.Color = 'r';

            while timeElapsed < timeRecording

                data{countData} = readline(s);
                timeStamps(countData) = toc;
                timeElapsed = toc;

                countData = countData + 1;
                app.Timer.Value = round(timeRecording - timeElapsed);


                if app.Timer.Value<60
                    app.Timer.BackgroundColor = 'r';
                end
                
            end
            
            timeElapsed = toc;
            disp(['End of Recording. Elapsed time: ',num2str(timeElapsed),' seconds.'])
            clear s;
            
            app.StartRecordingButton.Text = 'Saving values...';
            app.Lamp.Color = '#FFA500';
            
            timeStamps(isnan(timeStamps)) = [];
            dataSplit = data(~cellfun('isempty',data));

            valCells = cellfun(@(dataSplit) strsplit(dataSplit, ','), dataSplit, 'UniformOutput', false);
            accValues = zeros(length(valCells),3);
            for iCells = 1:1:length(valCells)
                accValues(iCells,:) = str2double(valCells{iCells});
            end

            figure,plot(accValues)
            clear data
            
            data.dateFormat = 'date (ddmmyy)';
            data.date = datestr(now,'ddmmyy');
            data.mouseId = mouseId;
            data.session = session;
            data.lengthOfRecordingFormat = 'seconds';
            data.lengthOfRecording = timeElapsed;
            data.timeStamps = timeStamps';
            data.accelerometerFormat = 'X Y Z';
            data.accelerometer = accValues;
            
%             outputName = [num2str(mouseId),'_',data.date,'_',num2str(durationRecording),'_',num2str(session)];
            save([app.outputFolder,app.outputName],'data');
        
        
        end
        
        function selectBehaviourAfterDialogBox(app,createNewVersion)
            disp(['create new version: ',num2str(createNewVersion)])
            disp(['output name before: ',app.outputName])
            if createNewVersion
                versionFiles = dir([app.outputFolder,app.outputName,'*.mat']);
                lastVersion = versionFiles(end).name;
%                 if ~isempty(versionFiles(end))
                if ~strcmp(lastVersion(end-7:end-6),'_v')
                    app.outputName = [app.outputName,'_v02'];
                    disp(['output name after: ',app.outputName])
                else
                    version = str2num(lastVersion(end-5:end-4));
                    version = num2str(version +1);
                    if length(version)<2
                        version = ['0',version];
                    end
                    app.outputName = [lastVersion(1:end-6),version];
                end
                uiresume;
            else
                startupFcn(app);                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.Lamp.Color = 'g';
            app.StartRecordingButton.Text = 'Start Recording';

            app.Timer.Value = 0;
            app.Timer.BackgroundColor = '#90EE90';

            if isfile('startupFile.mat')
                load('startupFile.mat')
                app.SelectOutputFolderEditField.Value = startupFile.outputFolder;
                app.MouseIDEditField.Value = startupFile.mouseId;
                app.SessionEditField.Value = startupFile.session;
                app.DurationofRecordingminEditField.Value = startupFile.durationRecording;
%                 app.SelectSerialPortDropDown.Value = startupFile.serialPort;
            end

        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.outputFolder = app.SelectOutputFolderEditField.Value;
            mouseId = app.MouseIDEditField.Value;
            session = app.SessionEditField.Value;
            durationRecording = app.DurationofRecordingminEditField.Value;
            serialPort = app.SelectSerialPortDropDown.Value;
            
            dateVal = datestr(now,'ddmmyy');
            app.outputName = [num2str(mouseId),'_',dateVal,'_',num2str(round(durationRecording)),'_',num2str(session)];
            if ~isempty(dir([app.outputFolder,app.outputName,'*.mat']))
                app.DialogApp = appDialogBox(app);
                disp(app.outputName)
                uiwait;
            end
            
            
            app.StartRecordingButton.Text = 'Configuring Port...';
            app.Lamp.Color = '#FFA500';

            initializeReadSerialPort(app,...
                mouseId,...
                session,...
                durationRecording,...
                serialPort);
            
            disp('end of recording')

            startupFile.outputFolder = app.SelectOutputFolderEditField.Value;
            startupFile.mouseId = app.MouseIDEditField.Value;
            startupFile.session = app.SessionEditField.Value;
            startupFile.durationRecording = app.DurationofRecordingminEditField.Value;

            save('startupFile.mat','startupFile')

            startupFcn(app)
        end

        % Value changed function: SelectSerialPortDropDown
        function SelectSerialPortDropDownValueChanged(app, event)
           
        end

        % Drop down opening function: SelectSerialPortDropDown
        function SelectSerialPortDropDownOpening(app, event)
            app.SelectSerialPortDropDown.Items = serialportlist("available")';
        end

        % Value changed function: Timer
        function TimerValueChanged(app, event)

        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            pathName = uigetdir('./');
            pathName = [pathName,'\'];
            app.SelectOutputFolderEditField.Value = pathName;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%            % Delete both apps
           delete(app.DialogApp)
           delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.StartRecordingButton = uibutton(app.Panel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.SelectOutputFolderEditField = uieditfield(app.Panel, 'text');
            app.Lamp = uilamp(app.Panel);
            app.Timer = uieditfield(app.Panel, 'numeric');
            app.Timer.ValueChangedFcn = createCallbackFcn(app, @TimerValueChanged, true);
            app.SelectOutputFolderButton = uibutton(app.Panel, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.SelectSerialPortDropDownLabel = uilabel(app.Panel);
            app.SelectSerialPortDropDownLabel.Text = 'Select Serial Port';
            app.SelectSerialPortDropDown = uidropdown(app.Panel);
            app.SelectSerialPortDropDown.Items = {};
            app.SelectSerialPortDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectSerialPortDropDownOpening, true);
            app.SelectSerialPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSerialPortDropDownValueChanged, true);
            app.SelectSerialPortDropDown.Value = {};
            app.DurationofRecordingminEditFieldLabel = uilabel(app.Panel);
            app.DurationofRecordingminEditFieldLabel.Text = 'Duration of Recording (min)';
            app.DurationofRecordingminEditField = uieditfield(app.Panel, 'numeric');
            app.MouseIDEditFieldLabel = uilabel(app.Panel);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.Panel, 'text');
            app.SessionEditFieldLabel = uilabel(app.Panel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.Panel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appRecording

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end